classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        TabGroup                 matlab.ui.container.TabGroup
        EfectosTab               matlab.ui.container.Tab
        AadirefectoButton        matlab.ui.control.Button
        Tree                     matlab.ui.container.Tree
        Node                     matlab.ui.container.TreeNode
        Node2                    matlab.ui.container.TreeNode
        Node3                    matlab.ui.container.TreeNode
        Node4                    matlab.ui.container.TreeNode
        Tab2                     matlab.ui.container.Tab
        InfoPanel                matlab.ui.container.Panel
        TextArea                 matlab.ui.control.TextArea
        CargaButton              matlab.ui.control.Button
        GuardarButton            matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        PsButton                 matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        rButton                  matlab.ui.control.StateButton
        GenSenalesPanel          matlab.ui.container.Panel
        TipodeondaDropDownLabel  matlab.ui.control.Label
        Tipodeonda               matlab.ui.control.DropDown
        HzLabel                  matlab.ui.control.Label
        FrecEditField            matlab.ui.control.EditField
        FrecuenciaLabel          matlab.ui.control.Label
        sLabel                   matlab.ui.control.Label
        DurationEditField        matlab.ui.control.EditField
        DuracionLabel            matlab.ui.control.Label
        AmplitudLabel            matlab.ui.control.Label
        AmplitEditField          matlab.ui.control.EditField
        VLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        data; % Description
        fs=8000;
        info;
        recorder=audiorecorder;
        audio;
    end
    
    methods (Access = private)
        
        function mostrarInfo(app,info)
            aux=split(string(info.Filename),'\');
            app.TextArea.Value=sprintf( ...
                'Fichero: %s\n\nDuración: %.2f s\n\nTasa de muestreo: %.2f Hz\n\nMuestras totales: %.0f\n', ...
                aux(length(aux)), ...
                string(info.Duration), ...
                string(app.info.SampleRate), ...
                string(app.info.TotalSamples));
        end
        
        function y = genSeno(~,amplitud,frec,duracion,Fs)
            muestras=duracion*Fs;
            t=linspace(0,duracion,muestras);
            y=amplitud*sin(2*pi*frec*t);
        end
        
        function y = genCos(~,amplitud,frec,duracion,Fs)
            muestras=duracion*Fs;
            t=linspace(0,duracion,muestras);
            y=amplitud*cos(2*pi*frec*t);
        end
        
        function y = genCuadrada(~,amplitud,frec,duracion,Fs)
            muestras=duracion*Fs;
            t=linspace(0,duracion,muestras);
            y=amplitud*square(2*pi*frec*t);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargaButton
        function CargaButtonPushed(app, event)
            [file,path]=uigetfile({'*.wav;*.flac;*.ogg;*.mp3;*.mp4;*.m4a'...
                ,'Extensiones Audio (*.wav;*.flac;*.ogg;*.mp3;*.mp4;*.m4a)'},'Seleccione el archivo de audio'); 
            if isequal(file,0)
            return
            end
            [app.data, app.fs]=audioread(fullfile(path,file));      %fullfile concatena el path y el archivo para poder leerlo desde donde sea
            app.info=audioinfo(fullfile(path,file));

            app.audio=audioplayer(app.data,app.fs);
            
            mostrarInfo(app,app.info);%función para mostrar informacion
            
            t=0:seconds(1/app.fs):seconds(app.info.Duration);
            t=t(1:end-1);
            
            plot(app.UIAxes,t,app.data);
            xlabel(app.UIAxes,'Tiempo [s]');
            ylabel(app.UIAxes,'Amplitud [V]');
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
             %play(app.player);
             sound(app.data,app.fs);
        end

        % Value changed function: rButton
        function rButtonValueChanged(app, event)
            value = app.rButton.Value;
            
            if value==1
                record(app.recorder);
            elseif value==0
                stop(app.recorder);
                grab=getaudiodata(app.recorder);
                
                t=0:seconds(1/app.recorder.SampleRate):seconds(app.recorder.TotalSamples*(1/app.recorder.SampleRate));
                t=t(1:end-1);
                
                plot(app.UIAxes,t,grab);
                [file,path] = uiputfile({'*.wav';'*.mp4';'*.ogg';'*.flac';'*.m4a'},'Guarda la grabación','grabacion.wav'); 
                audiowrite(fullfile(path,file),grab,app.recorder.SampleRate);
                
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
          clear sound;
        end

        % Button pushed function: PsButton
        function PsButtonPushed(app, event)
            pause(app.player);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EfectosTab = uitab(app.TabGroup);
            app.EfectosTab.Title = 'Efectos';
            app.AadirefectoButton = uibutton(app.EfectosTab, 'push');
            app.AadirefectoButton.Text = 'Añadir efecto';
            app.Tree = uitree(app.EfectosTab);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.InfoPanel = uipanel(app.UIFigure);
            app.InfoPanel.Title = 'Info';
            app.TextArea = uitextarea(app.InfoPanel);
            app.CargaButton = uibutton(app.UIFigure, 'push');
            app.CargaButton.ButtonPushedFcn = createCallbackFcn(app, @CargaButtonPushed, true);
            app.CargaButton.Text = 'Carga';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PsButton = uibutton(app.UIFigure, 'push');
            app.PsButton.ButtonPushedFcn = createCallbackFcn(app, @PsButtonPushed, true);
            app.PsButton.Text = 'Ps';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'S';
            app.rButton = uibutton(app.UIFigure, 'state');
            app.rButton.ValueChangedFcn = createCallbackFcn(app, @rButtonValueChanged, true);
            app.rButton.Text = 'r';
            app.GenSenalesPanel = uipanel(app.UIFigure);
            app.GenSenalesPanel.Title = 'Generador de señales simples';
            app.TipodeondaDropDownLabel = uilabel(app.GenSenalesPanel);
            app.TipodeondaDropDownLabel.Text = 'Tipo de onda';
            app.Tipodeonda = uidropdown(app.GenSenalesPanel);
            app.Tipodeonda.Items = {'Seno', 'Coseno', 'Option 3', 'Option 4'};
            app.Tipodeonda.Value = 'Seno';
            app.AmplitudLabel = uilabel(app.GenSenalesPanel);
            app.AmplitudLabel.Text = 'Amplitud';
            app.AmplitEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.VLabel = uilabel(app.GenSenalesPanel);
            app.VLabel.Text = 'V';
            app.HzLabel = uilabel(app.GenSenalesPanel);
            app.HzLabel.Text = 'Hz';
            app.FrecEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.FrecuenciaLabel = uilabel(app.GenSenalesPanel);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.sLabel = uilabel(app.GenSenalesPanel);
            app.sLabel.Text = 's';
            app.DurationEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.DuracionLabel = uilabel(app.GenSenalesPanel);
            app.DuracionLabel.Text = 'Duracion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end